classdef AtomicFisher < matlab.apps.AppBase
    %ATOMICFISHER Fisher game for KS3 science
    %   Author: Adil Wahab Bhatti
    %   Version: 2.0
    %   Date: 2025-06-11
    %   Made on MATLAB 2024b 
    %   May not work on previous versions

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MainMenuPanel            matlab.ui.container.Panel
        AtomicFisherLabel        matlab.ui.control.Label
        MenuBoat                 matlab.ui.control.Image
        StartBtn                 matlab.ui.control.Image
        bg_main                  matlab.ui.control.Image
        EndLevelPanel            matlab.ui.container.Panel
        StarsLabel               matlab.ui.control.Label
        TimeranoutLabel          matlab.ui.control.Label
        ExitButton_2             matlab.ui.control.Button
        RestartButton_2          matlab.ui.control.Button
        HighScoreLabel           matlab.ui.control.Label
        ScoreLabel_2             matlab.ui.control.Label
        TutorialPanel            matlab.ui.container.Panel
        ClickpaneltoplayLabel    matlab.ui.control.Label
        HowtoplayLabel_2         matlab.ui.control.Label
        HowtoplayLabel           matlab.ui.control.Label
        LevelMenuPanel           matlab.ui.container.Panel
        ClickpaneltoresumeLabel  matlab.ui.control.Label
        LevelpausedLabel         matlab.ui.control.Label
        ExitButton               matlab.ui.control.Button
        RestartButton            matlab.ui.control.Button
        FishingLevelPanel        matlab.ui.container.Panel
        CatchthenonmetalsLabel   matlab.ui.control.Label
        O                        matlab.ui.control.Image
        Na                       matlab.ui.control.Image
        K                        matlab.ui.control.Image
        Fe                       matlab.ui.control.Image
        C                        matlab.ui.control.Image
        He                       matlab.ui.control.Image
        H                        matlab.ui.control.Image
        fishUp4                  matlab.ui.control.Image
        fishUp2                  matlab.ui.control.Image
        fishUp1                  matlab.ui.control.Image
        fishUp3                  matlab.ui.control.Image
        rope                     matlab.ui.control.Image
        hook                     matlab.ui.control.Image
        levelBoat                matlab.ui.control.Image
        TimeLeftLabel            matlab.ui.control.Label
        fishL1                   matlab.ui.control.Image
        fishL2                   matlab.ui.control.Image
        fishR1                   matlab.ui.control.Image
        fishR2                   matlab.ui.control.Image
        ScoreLabel               matlab.ui.control.Label
        bg_underwater            matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        % Game state constants
        MENU_STATE = 0
        PLAYING_STATE = 2
        
        % Scoring constants
        CATCH_SCORE = 100
        WRONG_PENALTY = 50
        STAR_THRESHOLDS = [175, 325, 475]
        
        % Game variables
        gameTimer
        gameState = 0
        levelScore = 0
        levelHighScore = 0
        timeLeft = 120
        stars = 0
        
        % Fish system
        fish
        fishUp
        fishActive
        fishx
        fishy
        fishStartx = [-100, -121, 633, 626]
        fishAvailable
        fishSpawnTimer = 0
        fishSpeed = 15
        
        % Element system
        elements
        availableElements
        nonMetalElements = [1, 2, 3, 4] % H, He, C, O indices
        
        % Hook system
        hookx = 303
        hooky = 279
        ropeh = 56
        hookSpeedx = 3
        hookSpeedy = 5
        fishHooked = 0
        
        % UI bounds
        HOOK_BOUNDS = struct('minX', 220, 'maxX', 380, 'minY', 20, 'maxY', 330, 'boatY', 305)
    end
    
    methods (Access = private)
        
        function update(app, ~, ~)
            % Update time display
            remainingTime = (app.timeLeft - app.gameTimer.TasksExecuted) / 2;
            app.TimeLeftLabel.Text = sprintf('Time Left: %d', floor(remainingTime));

            if app.timeLeft <= app.gameTimer.TasksExecuted
                app.endLevel();
            else
                app.updateFishSpawning();
                app.updateAllFish();
            end
        end
        
        function endLevel(app)
            app.gameState = app.MENU_STATE;
            stop(app.gameTimer);
            
            app.levelHighScore = max(app.levelHighScore, app.levelScore);
            app.stars = app.calculateStars();
            
            app.showEndScreen();
        end
        
        function stars = calculateStars(app)
            thresholds = app.STAR_THRESHOLDS;
            if app.levelScore > thresholds(3)
                stars = 3;
            elseif app.levelScore > thresholds(2)
                stars = 2;
            elseif app.levelScore > thresholds(1)
                stars = 1;
            else
                stars = 0;
            end
        end
        
        function showEndScreen(app)
            app.activateComponent(app.EndLevelPanel);
            app.ScoreLabel_2.Text = sprintf('Score: %d', app.levelScore);
            app.HighScoreLabel.Text = sprintf('High Score: %d', app.levelHighScore);
            app.StarsLabel.Text = sprintf('Stars: %d', app.stars);
        end
        
        function updateFishSpawning(app)
            if app.fishSpawnTimer > 0
                app.fishSpawnTimer = app.fishSpawnTimer - 1;
            elseif ~isempty(app.fishAvailable)
                app.spawnRandomFish();
                app.fishSpawnTimer = randi([8, 14]);
            end
        end
        
        function spawnRandomFish(app)
            fishIdx = app.fishAvailable(randi(length(app.fishAvailable)));
            app.fishAvailable(app.fishAvailable == fishIdx) = [];
            
            % Reset fish position
            app.fishx(fishIdx) = app.fishStartx(fishIdx);
            app.fishy(fishIdx) = randi([15, 180]);
            
            % Assign random element
            elementIdx = app.availableElements(randi(length(app.availableElements)));
            app.availableElements(app.availableElements == elementIdx) = [];
            app.fishActive(fishIdx) = elementIdx;
            
            % Show fish and element
            app.elements(elementIdx).Visible = true;
            app.fish(fishIdx).Visible = true;
        end
        
        function updateAllFish(app)
            for i = 1:4
                if app.fishActive(i) > 0
                    app.updateSingleFish(i);
                end
            end
        end
        
        function updateSingleFish(app, fishIdx)
            if app.checkFishHookCollision(fishIdx)
                app.handleFishCatch(fishIdx);
            else
                app.moveFish(fishIdx);
            end
            app.updateFishPosition(fishIdx);
        end
        
        function collision = checkFishHookCollision(app, fishIdx)
            fishCenter = [app.fishx(fishIdx) + app.fish(fishIdx).Position(3)/2, ...
                         app.fishy(fishIdx) + app.fish(fishIdx).Position(4)/2];
            hookCenter = [app.hookx + 12, app.hooky + 23];
            
            distance = norm(fishCenter - hookCenter);
            collision = distance < 65 && app.fishHooked == 0;
        end
        
        function handleFishCatch(app, fishIdx)
            elementIdx = app.fishActive(fishIdx);
            
            if any(app.nonMetalElements == elementIdx) % Correct catch
                app.hookFish(fishIdx);
            else % Wrong catch
                app.penalizeFish(fishIdx);
            end
            
            app.releaseFish(fishIdx);
        end
        
        function hookFish(app, fishIdx)
            app.fishHooked = fishIdx;
            app.fishUp(fishIdx).Position(1) = app.hookx - 27;
            app.fishUp(fishIdx).Position(2) = app.hooky - 12;
            app.fishUp(fishIdx).Visible = true;
        end
        
        function penalizeFish(app, fishIdx)
            app.fishAvailable(end+1) = fishIdx;
            app.updateScore(-app.WRONG_PENALTY);
        end
        
        function releaseFish(app, fishIdx)
            app.fish(fishIdx).Visible = false;
            app.elements(app.fishActive(fishIdx)).Visible = false;
            app.availableElements(end+1) = app.fishActive(fishIdx);
            app.fishActive(fishIdx) = 0;
        end
        
        function moveFish(app, fishIdx)
            if fishIdx <= 2 % Left side fish
                if app.fishx(fishIdx) > 640
                    app.fishx(fishIdx) = app.fishStartx(fishIdx);
                else
                    app.fishx(fishIdx) = app.fishx(fishIdx) + app.fishSpeed;
                end
            else % Right side fish
                if app.fishx(fishIdx) < -120
                    app.fishx(fishIdx) = app.fishStartx(fishIdx);
                else
                    app.fishx(fishIdx) = app.fishx(fishIdx) - app.fishSpeed;
                end
            end
            
            % Random vertical movement
            app.fishy(fishIdx) = app.fishy(fishIdx) + randi(5) - 3;
        end
        
        function updateFishPosition(app, fishIdx)
            app.fish(fishIdx).Position(1) = app.fishx(fishIdx);
            app.fish(fishIdx).Position(2) = app.fishy(fishIdx);
            
            if app.fishActive(fishIdx) > 0
                elementPos = [app.fishx(fishIdx) + app.fish(fishIdx).Position(3)/2 - 22, ...
                             app.fishy(fishIdx) + app.fish(fishIdx).Position(4)/2 - 22];
                app.elements(app.fishActive(fishIdx)).Position(1:2) = elementPos;
            end
        end
        
        function updateScore(app, points)
            app.levelScore = max(0, app.levelScore + points);
            app.ScoreLabel.Text = sprintf('Score: %d', app.levelScore);
        end
        
        function initialiseLevel(app)
            % Reset game state
            app.gameState = app.MENU_STATE;
            app.levelScore = 0;
            app.stars = 0;
            app.timeLeft = 120;
            
            % Initialize arrays
            app.fish = [app.fishL1, app.fishL2, app.fishR1, app.fishR2];
            app.fishUp = [app.fishUp1, app.fishUp2, app.fishUp3, app.fishUp4];
            app.elements = [app.H, app.He, app.C, app.O, app.Na, app.Fe, app.K];
            
            % Reset fish system
            app.fishActive = zeros(1, 4);
            app.fishAvailable = 1:4;
            app.fishx = app.fishStartx;
            app.fishy = randi([15, 180], 1, 4);
            app.fishSpawnTimer = 0;
            app.fishSpeed = 15;
            
            % Reset elements
            app.availableElements = 1:7;
            
            % Reset hook
            app.hookx = 303;
            app.hooky = 279;
            app.ropeh = 56;
            app.fishHooked = 0;
            
            % Hide all fish and elements
            app.setVisibility([app.fish, app.fishUp, app.elements], false);
            
            % Update displays
            app.updateScore(0);
            app.TimeLeftLabel.Text = 'Time Left: 60';
            app.updateHookPositions();
            
            % Initialize timer
            app.gameTimer = timer('TimerFcn', @app.update, 'Period', 0.5, 'ExecutionMode', 'fixedRate');
            
            % Show panels
            app.activateComponent(app.FishingLevelPanel);
            app.activateComponent(app.TutorialPanel);
        end
        
        function setVisibility(app, components, visible)
            for i = 1:length(components)
                components(i).Visible = visible;
            end
        end
        
        function updateHookPositions(app)
            % Update hook position
            app.hook.Position(1) = app.hookx;
            app.hook.Position(2) = app.hooky;
            
            % Update boat position
            app.levelBoat.Position(1) = app.hookx - 169;
            
            % Update rope
            app.rope.Position(1) = app.hookx + 16;
            app.rope.Position(2) = app.hooky + 50;
            app.rope.Position(4) = app.ropeh;
            
            % Update hooked fish
            if app.fishHooked > 0
                app.fishUp(app.fishHooked).Position(1) = app.hookx - 27;
                app.fishUp(app.fishHooked).Position(2) = app.hooky - 12;
            end
        end
        
        function moveHook(app, direction)
            bounds = app.HOOK_BOUNDS;
            
            switch direction
                case 'up'
                    if app.hooky < bounds.maxY
                        app.hooky = app.hooky + app.hookSpeedy;
                        app.ropeh = app.ropeh - app.hookSpeedy;
                    end
                case 'down'
                    if app.hooky > bounds.minY
                        app.hooky = app.hooky - app.hookSpeedy;
                        app.ropeh = app.ropeh + app.hookSpeedy;
                    end
                case 'left'
                    if app.hookx > bounds.minX
                        app.hookx = app.hookx - app.hookSpeedx;
                    end
                case 'right'
                    if app.hookx < bounds.maxX
                        app.hookx = app.hookx + app.hookSpeedx;
                    end
            end
            
            app.updateHookPositions();
        end
        
        function releaseCaughtFish(app)
            if app.fishHooked > 0
                if app.hooky > app.HOOK_BOUNDS.boatY
                    app.updateScore(app.CATCH_SCORE);
                end
                
                app.fishAvailable(end+1) = app.fishHooked;
                app.fishUp(app.fishHooked).Visible = false;
                app.fishHooked = 0;
                app.fishSpeed = app.fishSpeed + 1;
            end
        end
        
        function activateComponent(app, component)
            component.Enable = true;
            component.Visible = true;
        end

        function deactivateComponent(app, component)
            component.Enable = false;
            component.Visible = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.gameState ~= app.MENU_STATE
                key = event.Key;
                keyMap = containers.Map({'downarrow', 'uparrow', 'leftarrow', 'rightarrow'}, ...
                                      {'down', 'up', 'left', 'right'});
                
                if keyMap.isKey(key)
                    app.moveHook(keyMap(key));
                elseif strcmp(key, 'space')
                    app.releaseCaughtFish();
                end
            end
        end

        % Image clicked function: StartBtn
        function StartBtnClicked(app, event)
            app.deactivateComponent(app.MainMenuPanel);
            app.initialiseLevel();
        end

        % Button down function: TutorialPanel
        function TutorialPanelButtonDown(app, event)
            app.deactivateComponent(app.TutorialPanel);
            app.gameState = app.PLAYING_STATE;
            start(app.gameTimer);            
        end

        % Image clicked function: bg_underwater
        function bg_underwaterImageClicked(app, event)
            if app.gameState ~= app.MENU_STATE
                app.gameState = app.MENU_STATE;
                app.timeLeft = app.timeLeft - app.gameTimer.TasksExecuted;
                stop(app.gameTimer);
                app.activateComponent(app.LevelMenuPanel);
            end
        end

        % Button down function: LevelMenuPanel
        function LevelMenuPanelButtonDown(app, event)
            app.deactivateComponent(app.LevelMenuPanel);
            app.gameState = app.PLAYING_STATE;
            start(app.gameTimer);
        end

        % Button pushed function: RestartButton, RestartButton_2
        function RestartButtonPushed(app, event)
            app.deactivateComponent(app.LevelMenuPanel);
            app.deactivateComponent(app.EndLevelPanel);
            app.initialiseLevel();
        end

        % Button pushed function: ExitButton, ExitButton_2
        function ExitButtonPushed(app, event)
            app.deactivateComponent(app.LevelMenuPanel);
            app.deactivateComponent(app.EndLevelPanel);
            app.activateComponent(app.MainMenuPanel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FishingLevelPanel = uipanel(app.UIFigure);
            app.FishingLevelPanel.Enable = 'off';
            app.FishingLevelPanel.Title = 'Fishing Level';
            app.FishingLevelPanel.Visible = 'off';
            app.bg_underwater = uiimage(app.FishingLevelPanel);
            app.bg_underwater.ImageClickedFcn = createCallbackFcn(app, @bg_underwaterImageClicked, true);
            app.bg_underwater.Tooltip = {''};
            app.bg_underwater.ImageSource = fullfile(pathToMLAPP, 'Images', 'Backgrounds', 'underwater.jpg');
            app.ScoreLabel = uilabel(app.FishingLevelPanel);
            app.ScoreLabel.Text = 'Score:';
            app.fishR2 = uiimage(app.FishingLevelPanel);
            app.fishR2.Visible = 'off';
            app.fishR2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'fish-joypixels.gif');
            app.fishR1 = uiimage(app.FishingLevelPanel);
            app.fishR1.Visible = 'off';
            app.fishR1.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'clownfish.gif');
            app.fishL2 = uiimage(app.FishingLevelPanel);
            app.fishL2.Visible = 'off';
            app.fishL2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'orangefish.gif');
            app.fishL1 = uiimage(app.FishingLevelPanel);
            app.fishL1.Visible = 'off';
            app.fishL1.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'yellowfish.gif');
            app.TimeLeftLabel = uilabel(app.FishingLevelPanel);
            app.TimeLeftLabel.Text = 'Time Left: ';
            app.levelBoat = uiimage(app.FishingLevelPanel);
            app.levelBoat.ImageSource = fullfile(pathToMLAPP, 'Images', 'Player', 'fisherboat.gif');
            app.hook = uiimage(app.FishingLevelPanel);
            app.hook.ImageSource = fullfile(pathToMLAPP, 'Images', 'Player', 'hook.gif');
            app.rope = uiimage(app.FishingLevelPanel);
            app.rope.ImageSource = fullfile(pathToMLAPP, 'Images', 'Player', 'rope.png');
            app.fishUp3 = uiimage(app.FishingLevelPanel);
            app.fishUp3.Visible = 'off';
            app.fishUp3.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'clownfish.png');
            app.fishUp1 = uiimage(app.FishingLevelPanel);
            app.fishUp1.Visible = 'off';
            app.fishUp1.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'yellowfish.png');
            app.fishUp2 = uiimage(app.FishingLevelPanel);
            app.fishUp2.Visible = 'off';
            app.fishUp2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'orangefish.png');
            app.fishUp4 = uiimage(app.FishingLevelPanel);
            app.fishUp4.Visible = 'off';
            app.fishUp4.ImageSource = fullfile(pathToMLAPP, 'Images', 'Fish', 'fish-joypixels.png');
            app.H = uiimage(app.FishingLevelPanel);
            app.H.Visible = 'off';
            app.H.ImageSource = fullfile(pathToMLAPP, 'Images', 'Elements', 'hydrogen.png');
            app.He = uiimage(app.FishingLevelPanel);
            app.He.Visible = 'off';
            app.He.ImageSource = fullfile(pathToMLAPP, 'Images', 'Elements', 'helium.png');
            app.C = uiimage(app.FishingLevelPanel);
            app.C.Visible = 'off';
            app.C.ImageSource = fullfile(pathToMLAPP, 'Images', 'Elements', 'carbon.png');
            app.Fe = uiimage(app.FishingLevelPanel);
            app.Fe.ImageSource = fullfile(pathToMLAPP, 'Images', 'Elements', 'iron.png');
            app.K = uiimage(app.FishingLevelPanel);
            app.K.Visible = 'off';
            app.K.ImageSource = fullfile(pathToMLAPP, 'Images', 'Elements', 'potassium.png');
            app.Na = uiimage(app.FishingLevelPanel);
            app.Na.Visible = 'off';
            app.Na.ImageSource = fullfile(pathToMLAPP, 'Images', 'Elements', 'sodium.png');
            app.O = uiimage(app.FishingLevelPanel);
            app.O.Visible = 'off';
            app.O.ImageSource = fullfile(pathToMLAPP, 'Images', 'Elements', 'oxygen.png');
            app.CatchthenonmetalsLabel = uilabel(app.FishingLevelPanel);
            app.CatchthenonmetalsLabel.Text = 'Catch the non-metals';
            app.LevelMenuPanel = uipanel(app.UIFigure);
            app.LevelMenuPanel.Enable = 'off';
            app.LevelMenuPanel.Title = 'Level Menu';
            app.LevelMenuPanel.Visible = 'off';
            app.LevelMenuPanel.ButtonDownFcn = createCallbackFcn(app, @LevelMenuPanelButtonDown, true);
            app.RestartButton = uibutton(app.LevelMenuPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.ExitButton = uibutton(app.LevelMenuPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.LevelpausedLabel = uilabel(app.LevelMenuPanel);
            app.LevelpausedLabel.Text = 'Level paused';
            app.ClickpaneltoresumeLabel = uilabel(app.LevelMenuPanel);
            app.ClickpaneltoresumeLabel.Text = 'Click panel to resume';
            app.TutorialPanel = uipanel(app.UIFigure);
            app.TutorialPanel.Enable = 'off';
            app.TutorialPanel.Title = 'Tutorial';
            app.TutorialPanel.Visible = 'off';
            app.TutorialPanel.ButtonDownFcn = createCallbackFcn(app, @TutorialPanelButtonDown, true);
            app.HowtoplayLabel = uilabel(app.TutorialPanel);
            app.HowtoplayLabel.Text = 'How to play';
            app.HowtoplayLabel_2 = uilabel(app.TutorialPanel);
            app.HowtoplayLabel_2.Text = 'Use the keys two move the hook. Hook the correct fish and reel the hook back to the boat. Press space to catch the fish. Click the background to pause the game.';
            app.ClickpaneltoplayLabel = uilabel(app.TutorialPanel);
            app.ClickpaneltoplayLabel.Text = 'Click panel to play';
            app.EndLevelPanel = uipanel(app.UIFigure);
            app.EndLevelPanel.Enable = 'off';
            app.EndLevelPanel.Title = 'End Level';
            app.EndLevelPanel.Visible = 'off';
            app.ScoreLabel_2 = uilabel(app.EndLevelPanel);
            app.ScoreLabel_2.Text = 'Score:';
            app.HighScoreLabel = uilabel(app.EndLevelPanel);
            app.HighScoreLabel.Text = 'High Score:';
            app.RestartButton_2 = uibutton(app.EndLevelPanel, 'push');
            app.RestartButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton_2.Text = 'Restart';
            app.ExitButton_2 = uibutton(app.EndLevelPanel, 'push');
            app.ExitButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton_2.Text = 'Exit';
            app.TimeranoutLabel = uilabel(app.EndLevelPanel);
            app.TimeranoutLabel.Text = 'Time ran out';
            app.StarsLabel = uilabel(app.EndLevelPanel);
            app.StarsLabel.Text = 'Stars:';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.bg_main = uiimage(app.MainMenuPanel);
            app.bg_main.ImageSource = fullfile(pathToMLAPP, 'Images', 'Backgrounds', 'island.gif');
            app.StartBtn = uiimage(app.MainMenuPanel);
            app.StartBtn.ImageClickedFcn = createCallbackFcn(app, @StartBtnClicked, true);
            app.StartBtn.ImageSource = fullfile(pathToMLAPP, 'Images', 'play.gif');
            app.MenuBoat = uiimage(app.MainMenuPanel);
            app.MenuBoat.ImageSource = fullfile(pathToMLAPP, 'Images', 'boat fishing.gif');
            app.AtomicFisherLabel = uilabel(app.MainMenuPanel);
            app.AtomicFisherLabel.Text = 'Atomic Fisher';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AtomicFisher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end